classdef tubes2app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        TabGroup                 matlab.ui.container.TabGroup
        Nomor1Tab                matlab.ui.container.Tab
        GridLayout2              matlab.ui.container.GridLayout
        GridLayout4              matlab.ui.container.GridLayout
        GridLayout6              matlab.ui.container.GridLayout
        ProcessButton            matlab.ui.control.Button
        InputButton              matlab.ui.control.Button
        GridLayout5              matlab.ui.container.GridLayout
        logLabel                 matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        MatrixConvTextArea       matlab.ui.control.TextArea
        MatrixConvTextAreaLabel  matlab.ui.control.Label
        GridLayout3              matlab.ui.container.GridLayout
        Image2                   matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        Nomor23Tab               matlab.ui.container.Tab
        GridLayout2_2            matlab.ui.container.GridLayout
        GridLayout4_2            matlab.ui.container.GridLayout
        GridLayout6_2            matlab.ui.container.GridLayout
        ProcessButton_2          matlab.ui.control.Button
        InputButton_2            matlab.ui.control.Button
        GridLayout5_2            matlab.ui.container.GridLayout
        GridLayout7              matlab.ui.container.GridLayout
        nEditField               matlab.ui.control.NumericEditField
        nEditFieldLabel          matlab.ui.control.Label
        d0EditField              matlab.ui.control.NumericEditField
        d0EditFieldLabel         matlab.ui.control.Label
        ButtonGroup              matlab.ui.container.ButtonGroup
        BHPFButton               matlab.ui.control.RadioButton
        GHPFButton               matlab.ui.control.RadioButton
        IHPFButton               matlab.ui.control.RadioButton
        BLPFButton               matlab.ui.control.RadioButton
        GLPFButton               matlab.ui.control.RadioButton
        ILPFButton               matlab.ui.control.RadioButton
        GaussianButton           matlab.ui.control.RadioButton
        MeanButton               matlab.ui.control.RadioButton
        logLabel_2               matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        GridLayout3_2            matlab.ui.container.GridLayout
        Image2_2                 matlab.ui.control.Image
        Image_2                  matlab.ui.control.Image
        Nomor4Tab                matlab.ui.container.Tab
        GridLayout2_3            matlab.ui.container.GridLayout
        GridLayout4_3            matlab.ui.container.GridLayout
        GridLayout6_3            matlab.ui.container.GridLayout
        ProcessButton_3          matlab.ui.control.Button
        InputButton_3            matlab.ui.control.Button
        GridLayout5_3            matlab.ui.container.GridLayout
        GridLayout7_3            matlab.ui.container.GridLayout
        LowGammaEditField        matlab.ui.control.NumericEditField
        LowGammaEditFieldLabel   matlab.ui.control.Label
        HighGammaEditField       matlab.ui.control.NumericEditField
        HighGammaEditFieldLabel  matlab.ui.control.Label
        GridLayout7_2            matlab.ui.container.GridLayout
        d0EditField_2            matlab.ui.control.NumericEditField
        d0EditField_2Label       matlab.ui.control.Label
        logLabel_3               matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        GridLayout3_3            matlab.ui.container.GridLayout
        Image2_3                 matlab.ui.control.Image
        Image_3                  matlab.ui.control.Image
    end

    
    properties (Access = private)
        inputImage0 % Description
        inputImage1
        inputImage2
        imgprcs
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.imgprcs = ImageProcessor;
        end

        % Button pushed function: InputButton
        function InputButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');  %open a mat file
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                % Construct the full file path
                image = imread(fullfile(path, file));

                if size(image, 3) ~= 3
                    image = cat(3, image, image, image);
                end
                app.inputImage0 = image;
                
                % Display the image in the app's UI Image component (app.Image)
                app.Image.ImageSource = app.inputImage0;
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            app.Label.Text = '';
            % Get the input text from the Text Area
            inputText = app.MatrixConvTextArea.Value;
        
            try
                % Convert the text input to a matrix
                matrix = str2num(char(inputText)); % Convert text to a matrix using str2num

                % Check if the conversion was successful
                if isempty(matrix)
                    error('Invalid matrix format. Please enter a valid matrix.');
                elseif size(matrix, 1) ~= size(matrix, 2)
                    error('Invalid matrix size. Please enter NxN matrix.');
                elseif isempty(app.inputImage0)
                    error('No image inputed.');
                end
                
                app.Label.Text = 'Processing...';
                drawnow;

                R = app.imgprcs.conv(app.inputImage0(:, :, 1), matrix);
                G = app.imgprcs.conv(app.inputImage0(:, :, 2), matrix);
                B = app.imgprcs.conv(app.inputImage0(:, :, 3), matrix);
                app.Label.Text = 'Done';

                app.Image2.ImageSource = cat(3, R, G, B);
            catch ME
                % Display error message if input is invalid
                app.Label.Text = ME.message;
                disp(ME.message);
            end
        end

        % Button pushed function: InputButton_2
        function InputButton_2Pushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');  %open a mat file
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                % Construct the full file path
                image = imread(fullfile(path, file));

                if size(image, 3) ~= 3
                    image = cat(3, image, image, image);
                end
                app.inputImage1 = image;
                
                % Display the image in the app's UI Image component (app.Image)
                app.Image_2.ImageSource = app.inputImage1;
            end
        end

        % Button pushed function: ProcessButton_2
        function ProcessButton_2Pushed(app, event)
            app.Label_2.Text = '';
        
            try
                d0 = app.d0EditField.Value;
                n = app.nEditField.Value;
                % Check if the conversion was successful
                if isempty(app.inputImage1)
                    error('No image inputed.');
                elseif n < 0
                    error('n is positive integer.');
                elseif d0 < 0
                    error('d0 is positive integer.');
                end
                
                R = app.inputImage1(:, :, 1);
                G = app.inputImage1(:, :, 2);
                B = app.inputImage1(:, :, 3);

                selectedButton = app.ButtonGroup.SelectedObject;

                app.Label_2.Text = 'Processing...';
                drawnow;

                switch selectedButton.Text
                    case 'Mean'
                        [r, c] = size(R);
                        s = min(r, c);
                        s = min(floor(s/20), 11);
                        if mod(s, 2) == 0
                            s = s + 1;
                        end
                        mask = ones(s, s) / (s * s);
                        R = app.imgprcs.conv(R, mask);
                        G = app.imgprcs.conv(G, mask);
                        B = app.imgprcs.conv(B, mask);
                    case 'Gaussian'
                        [r, c] = size(R);
                        s = min(r, c);
                        s = min(floor(s/20), 21);
                        if mod(s, 2) == 0
                            s = s + 1;
                        end
                        % Create a Gaussian mask of size n x n
                        sigma = 1;  % Standard deviation of the Gaussian
                        half_n = floor(s / 2);  % Half the size to create the grid
                        [x, y] = meshgrid(-half_n:half_n, -half_n:half_n);  % Create a grid
                        
                        % Gaussian function
                        mask = exp(-(x.^2 + y.^2) / (2 * sigma^2));
                        
                        % Normalize the mask so that the sum is 1
                        mask = mask / sum(mask(:));  % Normalize the mask

                        R = app.imgprcs.conv(R, mask);
                        G = app.imgprcs.conv(G, mask);
                        B = app.imgprcs.conv(B, mask);
                    case 'ILPF'
                        R = app.imgprcs.ipf(R, d0, false);
                        G = app.imgprcs.ipf(G, d0, false);
                        B = app.imgprcs.ipf(B, d0, false);
                    case 'GLPF'
                        R = app.imgprcs.gpf(R, d0, false);
                        G = app.imgprcs.gpf(G, d0, false);
                        B = app.imgprcs.gpf(B, d0, false);
                    case 'BLPF'
                        R = app.imgprcs.bpf(R, d0, n, false);
                        G = app.imgprcs.bpf(G, d0, n, false);
                        B = app.imgprcs.bpf(B, d0, n, false);
                    case 'IHPF'
                        R = app.imgprcs.ipf(R, d0, true);
                        G = app.imgprcs.ipf(G, d0, true);
                        B = app.imgprcs.ipf(B, d0, true);
                    case 'GHPF'
                        R = app.imgprcs.gpf(R, d0, true);
                        G = app.imgprcs.gpf(G, d0, true);
                        B = app.imgprcs.gpf(B, d0, true);
                    case 'BHPF'
                        R = app.imgprcs.bpf(R, d0, n, true);
                        G = app.imgprcs.bpf(G, d0, n, true);
                        B = app.imgprcs.bpf(B, d0, n, true);
                end

                app.Label_2.Text = 'Done';

                app.Image2_2.ImageSource = cat(3, R, G, B);
            catch ME
                % Display error message if input is invalid
                app.Label_2.Text = ME.message;
                disp(ME.message);
            end
        end

        % Button pushed function: InputButton_3
        function InputButton_3Pushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');  %open a mat file
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                % Construct the full file path
                image = imread(fullfile(path, file));

                if size(image, 3) ~= 3
                    image = cat(3, image, image, image);
                end
                app.inputImage2 = image;
                
                % Display the image in the app's UI Image component (app.Image)
                app.Image_3.ImageSource = app.inputImage2;
            end
        end

        % Button pushed function: ProcessButton_3
        function ProcessButton_3Pushed(app, event)
            app.Label_3.Text = '';
        
            try
                d0 = app.d0EditField_2.Value;
                hg = app.HighGammaEditField.Value;
                lg = app.LowGammaEditField.Value;
                % Check if the conversion was successful
                if isempty(app.inputImage2)
                    error('No image inputed.');
                elseif d0 < 0
                    error('d0 is positive integer.');
                elseif isempty(hg)
                    error('hg must not be empty.');
                elseif isempty(lg)
                    error('lg must not be empty.');
                end
                
                R = app.inputImage2(:, :, 1);
                G = app.inputImage2(:, :, 2);
                B = app.inputImage2(:, :, 3);

                app.Label_3.Text = 'Processing...';
                drawnow;

                R = app.imgprcs.pikirsendiri(R, d0, hg, lg, false);
                G = app.imgprcs.pikirsendiri(G, d0, hg, lg, false);
                B = app.imgprcs.pikirsendiri(B, d0, hg, lg, false);

                app.Label_3.Text = 'Done';

                app.Image2_3.ImageSource = cat(3, R, G, B);
            catch ME
                % Display error message if input is invalid
                app.Label_3.Text = ME.message;
                disp(ME.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Nomor1Tab = uitab(app.TabGroup);
            app.Nomor1Tab.Title = 'Nomor 1';
            app.GridLayout2 = uigridlayout(app.Nomor1Tab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Image = uiimage(app.GridLayout3);
            app.Image2 = uiimage(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.MatrixConvTextAreaLabel = uilabel(app.GridLayout5);
            app.MatrixConvTextAreaLabel.Text = 'Matrix Conv';
            app.MatrixConvTextArea = uitextarea(app.GridLayout5);
            app.MatrixConvTextArea.Placeholder = 'nxn matrix e.g. [1 1 1; 1 1 1; 1 1 1], ones(9 , 9) / 81, etc.';
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = '';
            app.logLabel = uilabel(app.GridLayout5);
            app.logLabel.Text = 'log:';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.InputButton = uibutton(app.GridLayout6, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.InputButton.Text = 'Input';
            app.ProcessButton = uibutton(app.GridLayout6, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.Nomor23Tab = uitab(app.TabGroup);
            app.Nomor23Tab.Title = 'Nomor 2 & 3';
            app.GridLayout2_2 = uigridlayout(app.Nomor23Tab);
            app.GridLayout3_2 = uigridlayout(app.GridLayout2_2);
            app.Image_2 = uiimage(app.GridLayout3_2);
            app.Image2_2 = uiimage(app.GridLayout3_2);
            app.GridLayout4_2 = uigridlayout(app.GridLayout2_2);
            app.GridLayout5_2 = uigridlayout(app.GridLayout4_2);
            app.Label_2 = uilabel(app.GridLayout5_2);
            app.Label_2.Text = '';
            app.logLabel_2 = uilabel(app.GridLayout5_2);
            app.logLabel_2.Text = 'log:';
            app.ButtonGroup = uibuttongroup(app.GridLayout5_2);
            app.ButtonGroup.Title = 'Button Group';
            app.MeanButton = uiradiobutton(app.ButtonGroup);
            app.MeanButton.Text = 'Mean';
            app.MeanButton.Value = true;
            app.GaussianButton = uiradiobutton(app.ButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.ILPFButton = uiradiobutton(app.ButtonGroup);
            app.ILPFButton.Text = 'ILPF';
            app.GLPFButton = uiradiobutton(app.ButtonGroup);
            app.GLPFButton.Text = 'GLPF';
            app.BLPFButton = uiradiobutton(app.ButtonGroup);
            app.BLPFButton.Text = 'BLPF';
            app.IHPFButton = uiradiobutton(app.ButtonGroup);
            app.IHPFButton.Text = 'IHPF';
            app.GHPFButton = uiradiobutton(app.ButtonGroup);
            app.GHPFButton.Text = 'GHPF';
            app.BHPFButton = uiradiobutton(app.ButtonGroup);
            app.BHPFButton.Text = 'BHPF';
            app.GridLayout7 = uigridlayout(app.GridLayout5_2);
            app.d0EditFieldLabel = uilabel(app.GridLayout7);
            app.d0EditFieldLabel.Text = 'd0';
            app.d0EditField = uieditfield(app.GridLayout7, 'numeric');
            app.d0EditField.Value = 50;
            app.nEditFieldLabel = uilabel(app.GridLayout7);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.GridLayout7, 'numeric');
            app.nEditField.Value = 2;
            app.GridLayout6_2 = uigridlayout(app.GridLayout4_2);
            app.InputButton_2 = uibutton(app.GridLayout6_2, 'push');
            app.InputButton_2.ButtonPushedFcn = createCallbackFcn(app, @InputButton_2Pushed, true);
            app.InputButton_2.Text = 'Input';
            app.ProcessButton_2 = uibutton(app.GridLayout6_2, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_2Pushed, true);
            app.ProcessButton_2.Text = 'Process';
            app.Nomor4Tab = uitab(app.TabGroup);
            app.Nomor4Tab.Title = 'Nomor 4';
            app.GridLayout2_3 = uigridlayout(app.Nomor4Tab);
            app.GridLayout3_3 = uigridlayout(app.GridLayout2_3);
            app.Image_3 = uiimage(app.GridLayout3_3);
            app.Image2_3 = uiimage(app.GridLayout3_3);
            app.GridLayout4_3 = uigridlayout(app.GridLayout2_3);
            app.GridLayout5_3 = uigridlayout(app.GridLayout4_3);
            app.Label_3 = uilabel(app.GridLayout5_3);
            app.Label_3.Text = '';
            app.logLabel_3 = uilabel(app.GridLayout5_3);
            app.logLabel_3.Text = 'log:';
            app.GridLayout7_2 = uigridlayout(app.GridLayout5_3);
            app.d0EditField_2Label = uilabel(app.GridLayout7_2);
            app.d0EditField_2Label.Text = 'd0';
            app.d0EditField_2 = uieditfield(app.GridLayout7_2, 'numeric');
            app.d0EditField_2.Value = 50;
            app.GridLayout7_3 = uigridlayout(app.GridLayout5_3);
            app.HighGammaEditFieldLabel = uilabel(app.GridLayout7_3);
            app.HighGammaEditFieldLabel.Text = 'High Gamma';
            app.HighGammaEditField = uieditfield(app.GridLayout7_3, 'numeric');
            app.HighGammaEditField.Value = 1.01;
            app.LowGammaEditFieldLabel = uilabel(app.GridLayout7_3);
            app.LowGammaEditFieldLabel.Text = 'Low Gamma';
            app.LowGammaEditField = uieditfield(app.GridLayout7_3, 'numeric');
            app.LowGammaEditField.Value = 0.99;
            app.GridLayout6_3 = uigridlayout(app.GridLayout4_3);
            app.InputButton_3 = uibutton(app.GridLayout6_3, 'push');
            app.InputButton_3.ButtonPushedFcn = createCallbackFcn(app, @InputButton_3Pushed, true);
            app.InputButton_3.Text = 'Input';
            app.ProcessButton_3 = uibutton(app.GridLayout6_3, 'push');
            app.ProcessButton_3.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_3Pushed, true);
            app.ProcessButton_3.Text = 'Process';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tubes2app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end