classdef tubes2app_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        GridLayout2              matlab.ui.container.GridLayout
        GridLayout4              matlab.ui.container.GridLayout
        GridLayout6              matlab.ui.container.GridLayout
        ProcessButton            matlab.ui.control.Button
        InputButton              matlab.ui.control.Button
        GridLayout5              matlab.ui.container.GridLayout
        MatrixConvTextArea       matlab.ui.control.TextArea
        MatrixConvTextAreaLabel  matlab.ui.control.Label
        GridLayout3              matlab.ui.container.GridLayout
        Image2                   matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        Tab2                     matlab.ui.container.Tab
    end

    
    properties (Access = private)
        selectedFile % To store the selected image file path
        selectedMenu % To store menu slected
        refImg % reference img
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function Tab2SizeChanged(app, event)

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % Read the image
            img = imread(app.selectedFile);

            image_result = noiseRemoval(img, 'salt & pepper', 'median');

            app.Image2.ImageSource = image_result;
        end

        % Button pushed function: InputButton
        function InputButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');  %open a mat file
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                % Construct the full file path
                app.selectedFile = fullfile(path, file);
                
                % Display the image in the app's UI Image component (app.Image)
                app.Image.ImageSource = app.selectedFile;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout2 = uigridlayout(app.Tab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Image = uiimage(app.GridLayout3);
            app.Image2 = uiimage(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.MatrixConvTextAreaLabel = uilabel(app.GridLayout5);
            app.MatrixConvTextAreaLabel.Text = 'Matrix Conv';
            app.MatrixConvTextArea = uitextarea(app.GridLayout5);
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.InputButton = uibutton(app.GridLayout6, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.InputButton.Text = 'Input';
            app.ProcessButton = uibutton(app.GridLayout6, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tubes2app_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end